classdef basic_view < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        MetadataLabel         matlab.ui.control.Label
        SelectImageLabel      matlab.ui.control.Label
        userPanel             matlab.ui.container.Panel
        Image_2               matlab.ui.control.Image
        SignedinasLabel       matlab.ui.control.Label
        lblUser               matlab.ui.control.Label
        Image3                matlab.ui.control.Image
        Image2                matlab.ui.control.Image
        Image                 matlab.ui.control.Image
        SourcecameraidentificationbyHellingerdistanceLabel  matlab.ui.control.Label
        ImagestoanalizeLabel  matlab.ui.control.Label
        tblImages             matlab.ui.control.Table
        btnMetadata           matlab.ui.control.Button
        tblMetadata           matlab.ui.control.Table
        btnOrigin             matlab.ui.control.Button
        txtCaptureDevice      matlab.ui.control.EditField
        btnInforme            matlab.ui.control.Button
        CapturedeviceLabel    matlab.ui.control.Label
        txtImageName          matlab.ui.control.EditField
        btnLoadImage          matlab.ui.control.Button
        ImageLabel            matlab.ui.control.Label
        btnLogout             matlab.ui.control.Button
    end

    
    properties (Access = private)
        conn % Connection DB
        user % Description
        idUser % Id user
        rslt % Storage resultset of the images (id's)
        imageBD % Storage resultset of image (name, model, brand)
        metadata % Storage metadata of image
    end
    
    methods (Access = private)
        
        % Function that obtains all the id's and filenames from a user
        function getDisputeImages(app)
            query = strcat("SELECT idQImage, file FROM DisputeImage WHERE idUser=",string(app.idUser));
            try
                app.rslt = fetch(app.conn, query);

                % Insert data in table
                app.tblImages.Data = app.rslt;
            catch e
                msgbox(strcat("Error ", char(e.message)), "Warning", "error");
            end
        end

        % Function that obtains all the metadata from the file and
        % inserts them into tbMetadata
        function getMetadata(app)
            queryMeta = strcat("SELECT file, path, des, orig, cam, adv_photo, gps, arch FROM DisputeImage ", ...
                                "WHERE idQImage = ", string(app.imageBD.idQImage));
            disp(queryMeta);
            % Execute the query and get the results in app.metadata
            try
                app.metadata = fetch(app.conn, queryMeta);

                % Insert the values of app.metadata into tbMetadata
                app.tblMetadata.Data = rows2vars(app.metadata);
            catch e
                msgbox(strcat("Error", char(e.message)), "Warning", "error");
            end
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, user)
            % Import paths
            addpath(strcat(pwd,'\modules\CameraFingerprint'));
            addpath(strcat(pwd,'\modules\ImageCrop'));
            addpath(strcat(pwd,'\modules\PDF'));
            addpath(strcat(pwd,'\modules\HLD'));
            addpath(strcat(pwd,'\modules\Metadata'));

            % Initialize general properties
            app.user = "basicUser";
            
            % Show the user in the lblUser
            app.lblUser.Text = app.user;
            
            % Initialize connection to DB
            app.conn = database('conn_scih', 'uqrpef8uvj0gk5k7', 'hS0C86ybuxlTFNVYSSto');

            % Get user's id
            query = strcat("SELECT idUser FROM User WHERE username = '", app.user,"'");
            try
                app.rslt = fetch(app.conn, query);

                % Set the user id property
                app.idUser = app.rslt.idUser;
    
                % Update table fcn
                app.getDisputeImages();
            catch e
                msgbox(strcat("Error", char(e.message)), "Warning", "error");
            end
        end

        % Button pushed function: btnLogout
        function btnLogoutButtonPushed(app, event)
            login();
            delete(app);
        end

        % Cell selection callback: tblImages
        function tblImagesCellSelection(app, event)

            % Erase value of capture device input text
            app.txtCaptureDevice.Value = "";

            % Erase values of table metadata
            app.tblMetadata.Data = {};

            % Create a new query for image data
            query = strcat("SELECT idQImage, file, brand, model FROM DisputeImage INNER JOIN Device ON (Device.idDevice = DisputeImage.idDevice) WHERE idQImage = ", string(app.rslt.idQImage(event.Indices(1,1))));

            try
                app.imageBD = fetch(app.conn, query);

                % Update values of name input text
                app.txtImageName.Value = string(app.imageBD.file);
    
                app.btnInforme.Enable = "on";
                app.btnOrigin.Enable = "on";
                app.btnMetadata.Enable = "on";
            catch e
                msgbox(strcat("Error", char(e.message)), "Warning", "error");
            end
        end

        % Button pushed function: btnLoadImage
        function btnLoadImageButtonPushed(app, event)
            [file, path] = uigetfile('*png; *.jpg; *.jpeg; *.HEIC', 'Select One or More Files', 'MultiSelect', 'on');
            if iscell(file)
                for i = 1:width(file)
                    % Move image to stage folder
                    copyfile(strcat(string(path),string(file(i))),"images\DisputeImage\");

                    % Metadata
                    meta = extractMetadata(string(path), string(file(i)));
                    
                    newDImage = table(1, app.idUser, string(file(i)), strcat("images\DisputeImage\"), string(meta.des), string(meta.orig), string(meta.cam), string(meta.adv_photo), string(meta.gps), string(meta.arch), ...
                                        'VariableNames', {'idDevice' 'idUser' 'file' 'path' 'des' 'orig' 'cam' 'adv_photo' 'gps' 'arch'});
                    try
                        % Try write in database
                        sqlwrite(app.conn, 'DisputeImage', newDImage);
                    catch e
                        % Show a msgbox to show the error
                        msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
                    end
                end
                % Show a msgbox to confirm the write
                msgbox("Dispute Images register succesfully", "Information", "help");
            else
                % Move image to stage folder
                copyfile(strcat(string(path),string(file)),"images\DisputeImage\");
                
                % Metadata
                meta = extractMetadata(string(path), string(file));

                newDImage = table(1, app.idUser, string(file), strcat("images\DisputeImage\"), string(meta.des), string(meta.orig), string(meta.cam), string(meta.adv_photo), string(meta.gps), string(meta.arch), ...
                                'VariableNames', {'idDevice' 'idUser' 'file' 'path' 'des' 'orig' 'cam' 'adv_photo' 'gps' 'arch'});
              
                try
                    % Try write in database
                    sqlwrite(app.conn, 'DisputeImage', newDImage);
                    % Show a msgbox to confirm the write
                    msgbox("Dispute Image register succesfully", "Information", "help");
                catch e
                    % Show a msgbox to show the error
                    msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
                end
            end
            app.getDisputeImages();
        end

        % Button pushed function: btnOrigin
        function btnOriginButtonPushed(app, event)
            % HLD
            % Crop dispute images
            cropAndSaveImg(app.conn, app.idUser, "dispute");

            % Extract PRNU from crops
            extractPRNU(app.conn, app.idUser, "dispute");

            % Extract and save PDF from PRNU in each one
            extractPDF(app.conn, app.idUser, "dispute");

            % Compare PDFs between reference and dispute
            extractAndSaveAreas(app.conn, app.idUser, "dispute")
            % ...
            % ...
            % Update values of capture device input text
            % app.txtCaptureDevice.Value = strcat(string(app.imageBD.brand)," ",string(app.imageBD.model));
        end

        % Button pushed function: btnMetadata
        function btnMetadataButtonPushed(app, event)
            app.getMetadata();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.btnLogout = uibutton(app.UIFigure, 'push');
            app.btnLogout.ButtonPushedFcn = createCallbackFcn(app, @btnLogoutButtonPushed, true);
            app.btnLogout.Text = 'Log Out';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image';
            app.btnLoadImage = uibutton(app.UIFigure, 'push');
            app.btnLoadImage.ButtonPushedFcn = createCallbackFcn(app, @btnLoadImageButtonPushed, true);
            app.btnLoadImage.Text = 'Load image';
            app.txtImageName = uieditfield(app.UIFigure, 'text');
            app.txtImageName.Editable = 'off';
            app.CapturedeviceLabel = uilabel(app.UIFigure);
            app.CapturedeviceLabel.Text = 'Capture device';
            app.btnInforme = uibutton(app.UIFigure, 'push');
            app.btnInforme.Enable = 'off';
            app.btnInforme.Text = 'Report';
            app.txtCaptureDevice = uieditfield(app.UIFigure, 'text');
            app.txtCaptureDevice.Editable = 'off';
            app.btnOrigin = uibutton(app.UIFigure, 'push');
            app.btnOrigin.ButtonPushedFcn = createCallbackFcn(app, @btnOriginButtonPushed, true);
            app.btnOrigin.Enable = 'off';
            app.btnOrigin.Text = 'Origin';
            app.tblMetadata = uitable(app.UIFigure);
            app.tblMetadata.ColumnName = '';
            app.tblMetadata.RowName = {};
            app.btnMetadata = uibutton(app.UIFigure, 'push');
            app.btnMetadata.ButtonPushedFcn = createCallbackFcn(app, @btnMetadataButtonPushed, true);
            app.btnMetadata.Enable = 'off';
            app.btnMetadata.Text = 'Metadata';
            app.tblImages = uitable(app.UIFigure);
            app.tblImages.ColumnName = '';
            app.tblImages.RowName = {};
            app.tblImages.CellSelectionCallback = createCallbackFcn(app, @tblImagesCellSelection, true);
            app.ImagestoanalizeLabel = uilabel(app.UIFigure);
            app.ImagestoanalizeLabel.Text = 'Images to analize';
            app.SourcecameraidentificationbyHellingerdistanceLabel = uilabel(app.UIFigure);
            app.SourcecameraidentificationbyHellingerdistanceLabel.Text = {'Source camera identification '; 'by Hellinger distance'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'src', 'SCI-H.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'src', 'utsjr_icon.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'src', 'cicata_icon.png');
            app.userPanel = uipanel(app.UIFigure);
            app.lblUser = uilabel(app.userPanel);
            app.lblUser.Text = 'User Admin';
            app.SignedinasLabel = uilabel(app.userPanel);
            app.SignedinasLabel.Text = 'Signed in as: ';
            app.Image_2 = uiimage(app.userPanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'src', 'icon-admin.png');
            app.SelectImageLabel = uilabel(app.UIFigure);
            app.SelectImageLabel.Text = 'Select Image';
            app.MetadataLabel = uilabel(app.UIFigure);
            app.MetadataLabel.Text = 'Metadata';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = basic_view(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end