classdef device_register < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image5                        matlab.ui.control.Image
        Image4                        matlab.ui.control.Image
        Image3                        matlab.ui.control.Image
        TabGroup                      matlab.ui.container.TabGroup
        DevicesTab                    matlab.ui.container.Tab
        userPanel                     matlab.ui.container.Panel
        Image                         matlab.ui.control.Image
        SignedinasLabel               matlab.ui.control.Label
        lblUser                       matlab.ui.control.Label
        btnHLDDevice                  matlab.ui.control.Button
        btnBackDevice                 matlab.ui.control.Button
        Panel_7                       matlab.ui.container.Panel
        swtGlobalDevices              matlab.ui.control.Switch
        ShowglobaldevicesSwitchLabel  matlab.ui.control.Label
        btnDeleteDevice               matlab.ui.control.Button
        ChooseadeviceLabel            matlab.ui.control.Label
        tbDevices                     matlab.ui.control.Table
        Panel_6                       matlab.ui.container.Panel
        CapturedeviceLabel            matlab.ui.control.Label
        btnRegister                   matlab.ui.control.Button
        txtModel                      matlab.ui.control.EditField
        ModelEditFieldLabel           matlab.ui.control.Label
        txtBrand                      matlab.ui.control.EditField
        BrandEditFieldLabel           matlab.ui.control.Label
        ImagesTab                     matlab.ui.container.Tab
        dpdDevice                     matlab.ui.control.DropDown
        DeviceDropDownLabel           matlab.ui.control.Label
        userPanel_2                   matlab.ui.container.Panel
        Image_2                       matlab.ui.control.Image
        SignedinasLabel_2             matlab.ui.control.Label
        lblUser_2                     matlab.ui.control.Label
        btnHLDImage                   matlab.ui.control.Button
        btnBackImage                  matlab.ui.control.Button
        Panel_11                      matlab.ui.container.Panel
        MetadataLabel                 matlab.ui.control.Label
        btnSearch                     matlab.ui.control.Button
        tbMetadata                    matlab.ui.control.Table
        Panel_10                      matlab.ui.container.Panel
        SourceImageLabel              matlab.ui.control.Label
        imgSource                     matlab.ui.control.Image
        btnLoadImage                  matlab.ui.control.Button
        Panel_9                       matlab.ui.container.Panel
        swtGlobalFiles                matlab.ui.control.Switch
        ShowglobalfilesLabel          matlab.ui.control.Label
        ManageimagesLabel             matlab.ui.control.Label
        btnDeleteImage                matlab.ui.control.Button
        tbImages                      matlab.ui.control.Table
    end

    properties (Access = private)
        user % User
        idUser % User id
        brand % Phone brand
        model % Phone model
        newDevice % New device Table
        conn % Connection
        query % Prepare query
        devices % Result of devices
        idDSelected % Id of the selected device
        idISelected % Id of the selected image
        file % Name of the file
        path % Path of the file
        queryGetDevice % Statement to get devices
        lastFile % Name of the last file
        dpdIdSelected % Id of the dropdown image selected
        queryGetRImage % Statement to get References Image
        rImages % Reference image
        metadata % Metadata
        queryGetMetadata % Statement to get metadata
        imageC % Image count
        updCount % Result of query to update image count
    end

    methods (Access = private)
        % Function that obtains all the devices from the database and
        % inserts them into tbDevices
        function getDevices(app)
            % Execute the query and get the results in app.devices
            app.devices = fetch(app.conn, app.queryGetDevice);
            % Insert the values of app.devices into tbDevices
            app.tbDevices.Data = app.devices;
            % This cycle repeats the height of the app.device 
            for i = 1:height(app.devices) 
                % Insert Items to dropdown dpdDevice
                app.dpdDevice.Items = strcat(string(app.devices.idDevice.'), ".- ", string(app.devices.brand.'), " ", string(app.devices.model.'));
            end
        end

        % Function that obtains all the Reference Images from the database 
        % and inserts them into tbImages
        function getRImages(app)
            % Execute the query and get the results in app.rImages
            app.rImages = fetch(app.conn, app.queryGetRImage);
            % Insert the values of app.rImages into tbImages
            app.tbImages.Data = app.rImages;
        end

        % Function that obtains all the metadata from the file and
        % inserts them into tbMetadata
        function getMetadata(app)
            % Execute the query and get the results in app.metadata
            app.metadata = fetch(app.conn, app.queryGetMetadata);
            % Insert the values of app.metadata into tbMetadata
            app.tbMetadata.Data = rows2vars(app.metadata).Var1;
        end

        % Function to update the image count in database 
        function updateImgCount(app)
            % Prepare the statement to get the image count to selected device 
            app.query = strcat("SELECT img_count FROM Device ", ... 
                        "WHERE idDevice = ", string(app.dpdIdSelected));

            % Try get the image count of selected device
            try
                app.imageC = fetch(app.conn, app.query);
            catch e
                msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
            end
            % Convert the image count to int and add one
            count = int32(app.imageC.img_count) + 1;
            % Prepare the statement to update the image count of selected
            % device
            app.query = strcat("UPDATE device SET img_count = ", string(count), ...
                        " WHERE idDevice = ", string(app.dpdIdSelected));
            % Try update the image count
            try
                app.updCount = fetch(app.conn, app.query);
            catch e
                msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
            end
            % Show devices
            app.getDevices();
        end

        % INPUT:
        %   IO             Imagen previamente orientada al valor 1.   
        %   tamB           Es el tamaño del bloque B, (500x500). 
        %   Nom_image      Nombre de la imagen a recortar.
        %   dirC_r         Es la ubicación donde se almacenará el recorte de la 
        %                  imagen.
        %   n              Número de imagen a recortar.
        function recorteCentroImagen_170120_ALQR(app, IO, tamB, Nom_image, dirC_r, n)
            % Obtiene las dimensiones de la imagen.
            [M, N, RGB] = size(IO); 
            
            % Localiza el centro de la imagen y busca la esquina superior izquierda del recorte en la imagen.
            pixelSM = ((M - tamB) / 2) + 1; % Tanto en filas
            pixelIN = ((N - tamB) / 2) + 1; % Como columnas
            
            % Así como la esquina inferior izquierda.
            tope = tamB - 1;
            LpixelM = pixelSM + tope;
            LpixelN = pixelIN + tope;
            
            % Genera el recorte en cada una de las capas de color.
            RecorteRGB(:, :, 1) = IO(pixelSM:LpixelM, pixelIN:LpixelN, 1);
            RecorteRGB(:, :, 2) = IO(pixelSM:LpixelM, pixelIN:LpixelN, 2);
            RecorteRGB(:, :, 3) = IO(pixelSM:LpixelM, pixelIN:LpixelN, 3);
            
            % Guarda el recorte con el nombre y en el directorio especificado
            guardaRecorteSG_040919_ALQR(RecorteRGB, Nom_image, n, dirC_r);
        end

        function IO = CorrectOrientatio_291019_ALQR(app, ubica_imagen)
            IM = imread(ubica_imagen);
            nom = ubica_imagen;
            % Obtiene los metadatos de las imágenes.
            info = imfinfo(nom);
            if isfield(info, 'Orientation')% Verifica que la etiqueta de orientación se encuentre presente.
                % Otiene el valor de orientación.
                orient = info(1).Orientation;
                switch orient
                    case 0
                        IO = IM;
                    case 1
                        IO = IM;
            %             disp('or1')
                        %La imagen tiene una orientación "normal".
                    case 2
                        IO = IM(:, end:-1:1, :);         %right to left
            %             disp('or2')
                    case 3
                        IO = IM(end:-1:1, end:-1:1, :);  %180 degree rotation
            %             disp('or3')
                    case 4
                        IO = IM(end:-1:1, :, :);         %bottom to top
            %             disp('or4')
                    case 5
                        IO = permute(IM, [2 1 3]);     %counterclockwise and upside down
            %             disp('or5')
                    case 6
                        IO = rot90(IM, 3);              %undo 90 degree by rotating 270
            %             disp('or6')
                    case 7
                        IO = rot90(IM(end:-1:1, :, :));  %undo counterclockwise and left/right
            %             disp('or7')
                    case 8
                        IO = rot90(IM);                %undo 270 rotation by rotating 90
            %             disp('or8')
                    otherwise
                        warning(sprintf('Orientación Desconocida %g ignored\n', orient));
                end
            else
            IO = IM;
            disp('No existen metadatos');
            end
        end

        %
        function DBguarda_1Recorte500RGB_300420_ALQR(app, directorio, dirC_1r_RGB)
            % Obtiene el nombre las imágenes y su ubicación en DB. 
            [nombre_imagen, Marca] = marcaRGB_280219_ALQR(directorio);
            % Obtiene el número de marcas.
            U = length(Marca);
            % Declaración de variables.
            d = 3;
            f = 1;
            g = 1;
            % Obtiene el número de modelos que es 5.
            T = 1;
            % Tamaño del recorte.
            tamB = 500;
            tic
                while d <= U
                    while f <= T%length(Marca(d).name)
                        while g <= length(Marca(d).name(f).name)
                            % Obtinen la ubicación de las imágenes en RGB.
                            ubica_imagen = Marca(d).name(f).name(g).name;
                            % Acceso al directorio donde se gurdarán los recortes.
                            c_marca = strtok(nombre_imagen(d).name(f).name(g).name, '_');
                            dirC_r = strcat(dirC_1r_RGB,c_marca, '\', c_marca, '_', num2str(f), '\');
                            % Obtiene el nombre de la imagen sin el formato(.jpg).
                            Nom_image = strtok(nombre_imagen(d).name(f).name(g).name, '.');
                            
                            % Corrige la Orientación de la imagen a la vista 1.
                            IO = CorrectOrientatio_291019_ALQR(ubica_imagen);
                            % Recorte del centro de la imagen y lo guarda en el directorio específicado. 
                            recorteCentroImagen_170120_ALQR(IO, tamB, Nom_image, dirC_r, g)
                            g = g + 1;
                        end
                        g = 1;
                        f = f + 1;
                    end
                    g = 1;
                    f = 1;
                    d = d + 1;
                end
            toc
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, user)
            % Get user submission by previous page
            app.user = user;
            % Show the user in lblUser
            app.lblUser.Text = app.user;
            app.lblUser_2.Text = app.user;
            % Create the connection with database
            app.conn = database('conn_scih', 'uqrpef8uvj0gk5k7', 'hS0C86ybuxlTFNVYSSto');
            % Prepare the statement with the username to get idUser
            app.query = strcat("SELECT idUser FROM User ", ...
                                    "WHERE username = '", app.user, "'");
            % Execute the query and get the results in app.idUser
            app.idUser = fetch(app.conn, app.query);
            % Prepare the statement with the idUser to only get User
            % Devices
            app.queryGetDevice = strcat("SELECT idDevice, brand, model, img_count, username FROM Device JOIN User ON User.idUser = Device.idUser ", ...
                                    "WHERE Device.idUser = ", string(app.idUser.idUser));
            % Call the function getDevices
            app.getDevices();
            app.dpdIdSelected = app.devices.idDevice(1);
            % Prepare the statement with the idUser to only get User
            % Reference Images
            app.queryGetRImage = strcat("SELECT idRImage, file, username FROM ReferenceImage JOIN User on User.idUser = ReferenceImage.idUser " + ...
                                        "WHERE ReferenceImage.idUser = ", string(app.idUser.idUser));
            % Call the function getRImages
            app.getRImages();
        end

        % Cell selection callback: tbDevices
        function tbDevicesCellSelection(app, event)
            % Get the selected index and with that get the id from the
            % app.devices object with the previously obtained index
            app.idDSelected = app.devices.idDevice(event.Indices(1));
        end

        % Button pushed function: btnRegister
        function btnRegisterButtonPushed(app, event)
            % Get brand of txtBrand
            app.brand = app.txtBrand.Value;
            % Get model of txtModel
            app.model = app.txtModel.Value;
            if app.brand ~= ""
                if app.model ~= ""
                    % Create a new table to insert a new row in Device table in the
                    % database
                    app.newDevice = table(app.idUser.idUser, string(app.brand), string(app.model), 0, ...
                                    'VariableNames', {'idUser', 'brand', 'model', 'img_count'});
                    % The try tries to execute all the instructions, in case of
                    % an error it exit and shows the error obtaining it with the
                    % catch
                    try 
                        % Try write in database
                        sqlwrite(app.conn, 'Device', app.newDevice);
                        % Show a msgbox to confirm the write
                        msgbox("Device register succesfull", "Information", "help");
                        % Clean the TextBoxs
                        app.txtBrand.Value = "";
                        app.txtModel.Value = "";
                    catch e
                        % Show a msgbox to show the error
                        msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
                    end
                    % Call the function getDevices
                    app.getDevices();
                else
                    msgbox("Please fill the Model", "Warning", "warn");
                end
            else
                msgbox("Please fill the Brand", "Warning", "warn");
            end
        end

        % Button pushed function: btnDeleteDevice
        function btnDeleteDeviceButtonPushed(app, event)
            % Prepare the statement with the idDevice to delete an device
            app.query = strcat("DELETE FROM Device ", ...
                                    "WHERE idDevice = ", string(app.idDSelected));
             % The try tries to execute all the instructions, in case of
            % an error it exit and shows the error obtaining it with the
            % catch
            try
                % Execute the query and get the results in app.devices
                app.devices = fetch(app.conn, app.query);
            catch e
                % Show a msgbox to show the error
                msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
            end
            % Call the function getDevices 
            app.getDevices();
        end

        % Callback function: btnLoadImage, imgSource
        function btnLoadImageButtonPushed(app, event)
            % Function than obtains the file and the path to images loaded
            [app.file, app.path] = uigetfile('*png; *.jpg; *.jpeg; *.HEIC', 'Select One or More Files', 'MultiSelect', 'on');
            % Check if the file is a cell or a object
            if iscell(app.file)
                % Erase the last file 
                app.lastFile = "";
                % Repeats the with of the file
                for i = 1:width(app.file)
                    % Read the image and save the object in img
                    img = imread(strcat(string(app.path),string(app.file(i))));
                    % Save the image in path "ReferenceImage" 
                    imwrite(img,strcat(".\images\ReferenceImage\", string(app.file(i))));
                    % Prepare the register of the new Reference Image
                    newRImage = table(str2num(string(app.dpdIdSelected)), app.idUser.idUser, string(app.file(i)), ".\images\ReferenceImage\", "test", "test", "test", "test", "test", "test", ...
                                    'VariableNames', {'idDevice' 'idUser' 'file' 'path' 'des' 'orig' 'cam' 'adv_photo' 'gps' 'arch'});
                    try
                        % Try write in database
                        sqlwrite(app.conn, 'ReferenceImage', newRImage);
                        app.updateImgCount();
                    catch e
                        % Show a msgbox to show the error
                        msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
                    end
                    % Save the last file in propertie app.lastFile
                    app.lastFile = string(app.file(i));
                end
                % Show the last image
                app.imgSource.ImageSource = strcat(string(app.path), app.lastFile);
                % Show a msgbox to confirm the write
                msgbox("References Images register succesfull", "Information", "help");
            else
                % This case are with only one file loaded
                % Read the image and save the object in img
                img = imread(strcat(string(app.path),string(app.file)));
                % Save the image in path "ReferenceImage" 
                imwrite(img,strcat(".\images\ReferenceImage\", string(app.file)));
                % Prepare the register of the new Reference Image
                newRImage = table(str2num(string(app.dpdIdSelected)), app.idUser.idUser, string(app.file), strcat(".\images\ReferenceImage\"), "test", "test", "test", "test", "test", "test", ...
                                'VariableNames', {'idDevice' 'idUser' 'file' 'path' 'des' 'orig' 'cam' 'adv_photo' 'gps' 'arch'});
                try
                    % Try write in database
                    sqlwrite(app.conn, 'ReferenceImage', newRImage);
                    app.updateImgCount();
                    % Show a msgbox to confirm the write
                    msgbox("Reference Image register succesfull", "Information", "help");
                catch e
                    % Show a msgbox to show the error
                    msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
                end
                % Show the image
                app.imgSource.ImageSource = strcat(string(app.path), app.file);
            end
            % Get the references Image
            app.getRImages();
        end

        % Button pushed function: btnBackDevice, btnBackImage
        function btnBackDeviceButtonPushed(app, event)
            % Return to admin_view form and send the parameter app.user
            admin_view(app.user);
            % Close this form
            delete(app);
        end

        % Value changed function: swtGlobalDevices
        function swtGlobalDevicesValueChanged(app, event)
            % Check the switch Global Devices value
            if string(app.swtGlobalDevices.Value) == "On"
                % Prepare the statement to get all User Devices
                app.queryGetDevice = strcat("SELECT idDevice, brand, model, img_count, username FROM Device JOIN User ON User.idUser = Device.idUser");
            else
                % Prepare the statement with the idUser to only get User
                % Devices
                app.queryGetDevice = strcat("SELECT idDevice, brand, model, img_count, username FROM Device JOIN User ON User.idUser = Device.idUser ", ...
                                    "WHERE Device.idUser = ", string(app.idUser.idUser));
            end    
            % Get Devices
            app.getDevices();
        end

        % Value changed function: dpdDevice
        function dpdDeviceValueChanged(app, event)
            % Split the value in the dot and save in temp
            temp = split(app.dpdDevice.Value, ".");
            % Only save the first part of temp and save in propertie
            % dpdIdSelected
            app.dpdIdSelected = str2double(string(temp(1)));
        end

        % Value changed function: swtGlobalFiles
        function swtGlobalFilesValueChanged(app, event)
            % Check the switch Global Files value
            if string(app.swtGlobalFiles.Value) == "On"
                % Prepare the statement to get all User Reference Images
                app.queryGetRImage = strcat("SELECT idRImage, file, username FROM ReferenceImage JOIN User on User.idUser = ReferenceImage.idUser");
            else
                % Prepare the statement with the idUser to only get User
                % Reference Images
                app.queryGetRImage = strcat("SELECT idRImage, file, username FROM ReferenceImage JOIN User on User.idUser = ReferenceImage.idUser ", ...
                                    "WHERE ReferenceImage.idUser = ", string(app.idUser.idUser));
            end    
            % Get References Images
            app.getRImages();
        end

        % Cell selection callback: tbImages
        function tbImagesCellSelection(app, event)
            % Get row selected of the table and only select the id
            app.idISelected = app.rImages.idRImage(event.Indices(1));
            % Show the image selected in the ImageSource
            app.imgSource.ImageSource = strcat(".\images\ReferenceImage\", app.rImages.file(event.Indices(1)));
            % Prepare the statement with the idRImage to get Metadata from
            % image with these id
            app.queryGetMetadata = strcat("SELECT file, path, des, orig, cam, adv_photo, gps, arch FROM ReferenceImage ", ...
                                "WHERE idRImage = ", string(app.idISelected));
            % Get metadata
            app.getMetadata();
        end

        % Button pushed function: btnHLDImage
        function btnHLDImageButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DevicesTab = uitab(app.TabGroup);
            app.DevicesTab.Title = 'Devices';
            app.Panel_6 = uipanel(app.DevicesTab);
            app.BrandEditFieldLabel = uilabel(app.Panel_6);
            app.BrandEditFieldLabel.Text = 'Brand';
            app.txtBrand = uieditfield(app.Panel_6, 'text');
            app.ModelEditFieldLabel = uilabel(app.Panel_6);
            app.ModelEditFieldLabel.Text = 'Model';
            app.txtModel = uieditfield(app.Panel_6, 'text');
            app.btnRegister = uibutton(app.Panel_6, 'push');
            app.btnRegister.ButtonPushedFcn = createCallbackFcn(app, @btnRegisterButtonPushed, true);
            app.btnRegister.Text = 'Register';
            app.CapturedeviceLabel = uilabel(app.Panel_6);
            app.CapturedeviceLabel.Text = 'Capture device';
            app.Panel_7 = uipanel(app.DevicesTab);
            app.tbDevices = uitable(app.Panel_7);
            app.tbDevices.ColumnName = {'idDevice'; 'Brand'; 'Model'; 'Image #'; 'User'};
            app.tbDevices.RowName = {};
            app.tbDevices.CellSelectionCallback = createCallbackFcn(app, @tbDevicesCellSelection, true);
            app.ChooseadeviceLabel = uilabel(app.Panel_7);
            app.ChooseadeviceLabel.Text = 'Choose a device';
            app.btnDeleteDevice = uibutton(app.Panel_7, 'push');
            app.btnDeleteDevice.ButtonPushedFcn = createCallbackFcn(app, @btnDeleteDeviceButtonPushed, true);
            app.btnDeleteDevice.Text = 'Delete';
            app.ShowglobaldevicesSwitchLabel = uilabel(app.Panel_7);
            app.ShowglobaldevicesSwitchLabel.Text = 'Show global devices';
            app.swtGlobalDevices = uiswitch(app.Panel_7, 'slider');
            app.swtGlobalDevices.ValueChangedFcn = createCallbackFcn(app, @swtGlobalDevicesValueChanged, true);
            app.btnBackDevice = uibutton(app.DevicesTab, 'push');
            app.btnBackDevice.ButtonPushedFcn = createCallbackFcn(app, @btnBackDeviceButtonPushed, true);
            app.btnBackDevice.Text = 'Back';
            app.btnHLDDevice = uibutton(app.DevicesTab, 'push');
            app.btnHLDDevice.Text = 'HLD';
            app.userPanel = uipanel(app.DevicesTab);
            app.lblUser = uilabel(app.userPanel);
            app.lblUser.Text = 'User Admin';
            app.SignedinasLabel = uilabel(app.userPanel);
            app.SignedinasLabel.Text = 'Signed in as: ';
            app.Image = uiimage(app.userPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'src', 'icon-admin.png');
            app.ImagesTab = uitab(app.TabGroup);
            app.ImagesTab.Title = 'Images';
            app.Panel_9 = uipanel(app.ImagesTab);
            app.tbImages = uitable(app.Panel_9);
            app.tbImages.ColumnName = {'idRImage'; 'File'; 'User'};
            app.tbImages.RowName = {};
            app.tbImages.CellSelectionCallback = createCallbackFcn(app, @tbImagesCellSelection, true);
            app.tbImages.Multiselect = 'off';
            app.btnDeleteImage = uibutton(app.Panel_9, 'push');
            app.btnDeleteImage.Text = 'Delete';
            app.ManageimagesLabel = uilabel(app.Panel_9);
            app.ManageimagesLabel.Text = 'Manage images';
            app.ShowglobalfilesLabel = uilabel(app.Panel_9);
            app.ShowglobalfilesLabel.Text = 'Show global files';
            app.swtGlobalFiles = uiswitch(app.Panel_9, 'slider');
            app.swtGlobalFiles.ValueChangedFcn = createCallbackFcn(app, @swtGlobalFilesValueChanged, true);
            app.Panel_10 = uipanel(app.ImagesTab);
            app.btnLoadImage = uibutton(app.Panel_10, 'push');
            app.btnLoadImage.ButtonPushedFcn = createCallbackFcn(app, @btnLoadImageButtonPushed, true);
            app.btnLoadImage.Text = 'Load Image';
            app.imgSource = uiimage(app.Panel_10);
            app.imgSource.ImageClickedFcn = createCallbackFcn(app, @btnLoadImageButtonPushed, true);
            app.SourceImageLabel = uilabel(app.Panel_10);
            app.SourceImageLabel.Text = 'Source Image';
            app.Panel_11 = uipanel(app.ImagesTab);
            app.tbMetadata = uitable(app.Panel_11);
            app.tbMetadata.ColumnName = {'Data'};
            app.tbMetadata.RowName = {'''Description'';'; '''Source'';'; '''Image'';'; '''Camera'';'; '''Advance photo'';'; '''GPS'';'; '''File'';'};
            app.tbMetadata.Multiselect = 'off';
            app.btnSearch = uibutton(app.Panel_11, 'push');
            app.btnSearch.Text = 'Search';
            app.MetadataLabel = uilabel(app.Panel_11);
            app.MetadataLabel.Text = 'Metadata';
            app.btnBackImage = uibutton(app.ImagesTab, 'push');
            app.btnBackImage.ButtonPushedFcn = createCallbackFcn(app, @btnBackDeviceButtonPushed, true);
            app.btnBackImage.Text = 'Back';
            app.btnHLDImage = uibutton(app.ImagesTab, 'push');
            app.btnHLDImage.ButtonPushedFcn = createCallbackFcn(app, @btnHLDImageButtonPushed, true);
            app.btnHLDImage.Text = 'HLD';
            app.userPanel_2 = uipanel(app.ImagesTab);
            app.lblUser_2 = uilabel(app.userPanel_2);
            app.lblUser_2.Text = 'User Admin';
            app.SignedinasLabel_2 = uilabel(app.userPanel_2);
            app.SignedinasLabel_2.Text = 'Signed in as: ';
            app.Image_2 = uiimage(app.userPanel_2);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'src', 'icon-admin.png');
            app.DeviceDropDownLabel = uilabel(app.ImagesTab);
            app.DeviceDropDownLabel.Text = 'Device';
            app.dpdDevice = uidropdown(app.ImagesTab);
            app.dpdDevice.Items = {};
            app.dpdDevice.ValueChangedFcn = createCallbackFcn(app, @dpdDeviceValueChanged, true);
            app.dpdDevice.Value = {};
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'src', 'utsjr_icon.png');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'src', 'SCI-H.png');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'src', 'cicata_icon.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = device_register(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end