classdef login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figLogin                matlab.ui.Figure
        imgUTSJR                matlab.ui.control.Image
        imgCICATA               matlab.ui.control.Image
        btnLogin                matlab.ui.control.Button
        txtPassword             matlab.ui.control.EditField
        txtUser                 matlab.ui.control.EditField
        PasswordEditFieldLabel  matlab.ui.control.Label
        UserEditFieldLabel      matlab.ui.control.Label
        lblLogin                matlab.ui.control.Label
        lblVersion              matlab.ui.control.Label
        lblDescription          matlab.ui.control.Label
        lblAcronym              matlab.ui.control.Label
        lblWelcome              matlab.ui.control.Label
    end

    
    properties (Access = private)
        conn % Connection DB
        user % Username
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create the connection with the ODBC connector previously created database in "databaseExplorer"
            app.conn = database('conn_scih', 'root', 'root');
        end

        % Button pushed function: btnLogin
        function btnLoginButtonPushed(app, event)
            % Sets to app.user property the value by txtUser
            app.user = app.txtUser.Value;
            % Prepare query statement with user condition
            query = strcat("SELECT password, type FROM User ", ...
                "WHERE username = '", app.user, "'");
            % Execute fetch with the connection and query
            rslt = fetch(app.conn, query);
            if height(rslt.password) == 1
                % Password check in DB with password typed by user
                if string(rslt.password) == string(app.txtPassword.Value) 
                    % User type check
                    switch string(rslt.type)
                        case "admin"
                            % Display a message indicating the account type
                            %msgbox("Account type: ADMIN", "Information", "info");
                            admin_view(app.user); 
                            delete(app);
                        case "basic"
                            % Display a message indicating the account type
                            msgbox("Account type: BASIC", "Information", "info");
                    end
                else
                    % Display a message indicating that an incorrect password
                    % was entered
                    msgbox("Incorrect Password", "Error", "error");
                end
            else
                % Display a message indicating that the user doesn't exist
                msgbox("This user doesn't exist", "Error", "error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figLogin = uifigure('Visible', 'off');
            app.figLogin.Name = 'Login';
            app.lblWelcome = uilabel(app.figLogin);
            app.lblWelcome.Text = 'Welcome';
            app.lblAcronym = uilabel(app.figLogin);
            app.lblAcronym.Text = 'SCI-H';
            app.lblDescription = uilabel(app.figLogin);
            app.lblDescription.Text = 'Source camera identification by Hellinger distance';
            app.lblVersion = uilabel(app.figLogin);
            app.lblVersion.Text = 'V1.0 10/11/2022';
            app.lblLogin = uilabel(app.figLogin);
            app.lblLogin.Text = 'LOGIN';
            app.UserEditFieldLabel = uilabel(app.figLogin);
            app.UserEditFieldLabel.Text = 'User';
            app.PasswordEditFieldLabel = uilabel(app.figLogin);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.txtUser = uieditfield(app.figLogin, 'text');
            app.txtUser.Placeholder = 'User';
            app.txtPassword = uieditfield(app.figLogin, 'text');
            app.txtPassword.Placeholder = 'Password';
            app.btnLogin = uibutton(app.figLogin, 'push');
            app.btnLogin.ButtonPushedFcn = createCallbackFcn(app, @btnLoginButtonPushed, true);
            app.btnLogin.Text = 'Login';
            app.imgCICATA = uiimage(app.figLogin);
            app.imgCICATA.ImageSource = fullfile(pathToMLAPP, 'src', 'cicata_icon.png');
            app.imgUTSJR = uiimage(app.figLogin);
            app.imgUTSJR.ImageSource = fullfile(pathToMLAPP, 'src', 'utsjr_icon.png');
            % Show the figure after all components are created
            app.figLogin.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figLogin)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figLogin)
        end
    end
end